classdef NewMint < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NumIntGraphingCalculatorUIFigure  matlab.ui.Figure
        SettingsMenu               matlab.ui.container.Menu
        expSettings                matlab.ui.container.Menu
        changeBoundsOfSteps        matlab.ui.container.Menu
        animationMenu              matlab.ui.container.Menu
        animHigherQualityMenu      matlab.ui.container.Menu
        animHigherPerformanceMenu  matlab.ui.container.Menu
        animDisabledMenu           matlab.ui.container.Menu
        AboutMenu                  matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        SumErrorLabel              matlab.ui.control.Label
        SumLabel                   matlab.ui.control.Label
        IntegralLabel              matlab.ui.control.Label
        StepsBox                   matlab.ui.control.NumericEditField
        upperBoundBox              matlab.ui.control.NumericEditField
        lowerBoundBox              matlab.ui.control.NumericEditField
        StepsSlider                matlab.ui.control.Slider
        StepsSliderLabel           matlab.ui.control.Label
        SelectSumTypeButtonGroup   matlab.ui.container.ButtonGroup
        SimpsonButton              matlab.ui.control.ToggleButton
        TrapButton                 matlab.ui.control.ToggleButton
        MidButton                  matlab.ui.control.ToggleButton
        RightButton                matlab.ui.control.ToggleButton
        LeftButton                 matlab.ui.control.ToggleButton
        bEditField                 matlab.ui.control.NumericEditField
        bEditFieldLabel            matlab.ui.control.Label
        aEditFieldLabel            matlab.ui.control.Label
        aEditField                 matlab.ui.control.NumericEditField
        gDrawButton                matlab.ui.control.Button
        gTypeButton                matlab.ui.control.Button
        gClearDrawingButton        matlab.ui.control.Button
        gxEditField                matlab.ui.control.EditField
        gStartDrawingButton        matlab.ui.control.Button
        gxEditFieldLabel           matlab.ui.control.Label
        fDrawButton                matlab.ui.control.Button
        fTypeButton                matlab.ui.control.Button
        fClearDrawingButton        matlab.ui.control.Button
        fxEditField                matlab.ui.control.EditField
        fStartDrawingButton        matlab.ui.control.Button
        fxEditFieldLabel           matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        mousePosLabel              matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % Define x as symbolic variable
        x = sym("x")
        % Most up to date function variables -- direct user input
        newFx = symfun(0, sym("x"));
        newGx = symfun(0, sym("x"));
        % Slightly outdated function variables -- updated after anim.
        currentFx = symfun(0, sym("x"));
        currentGx = symfun(0, sym("x"));
        
        % Last input stored as a serial date number to avoid animation
        % problems from input spam
        fxLastIn;
        gxLastIn;

        % References to plotted fx, gx, and sum visualization
        fxPlot;
        gxPlot;
        sumPlot;

        % Input data for calculating sums
        sumSteps = 20;
        lowerBound = -10;
        upperBound = 10;
        sumType = 'L';

        % Should animation be played
        animationEnabled = false;

        % Number of points to be set during animation
        animationPoints;

        % Number of frames in animation
        animationFrames;

        % Input Keys
        ctrlDown = false;
        shiftDown = false;
        zDown = false;

        % Which drawing is currently active, if any.
        activeDrawing = '';

        % Most recently drawn point
        drawnLinePoint;

        % Lists to contain drawn piecewise functions
        fxDrawing = [0 0];
        gxDrawing = [0 0];

        % Is the line being drawn in increasing or decreasing order?
        increasing;
    end
    
    methods (Access = private)
        
        function shouldRedraw(app)
            %% Check if input function changed
            if app.newFx ~= app.currentFx
                if app.animationEnabled 
                    app.fxLastIn = now;
                    inputLimiter(app, 'f');
                else
                    redrawFns(app, 'f');
                    redrawSums(app);
                end
            elseif app.newGx ~= app.currentGx
                if app.animationEnabled 
                    app.gxLastIn = now;
                    inputLimiter(app, 'g');
                else
                    redrawFns(app, 'g');
                    redrawSums(app);
                end
            end
        end
        
        function redrawAnim(app, currentFunction)
            % Store current screen bounds so screen doesn't move during anim
            xLimits = app.UIAxes.XLim;
            yLimits = app.UIAxes.YLim;
            % Subdivide screen into n steps with n = visualQuality
            xSteps = linspace(xLimits(1), xLimits(2), app.animationPoints);

            % Preallocate operating/static function arrays
            operatingFunction = zeros(app.animationFrames, app.animationPoints);

            %% Set matrices for animated & static variables
            if currentFunction == 'f'
                for n = 1:1:app.animationFrames
                    % Calculate interp value at current iteration
                    intVal = n / app.animationFrames;
                    intFunc = intVal^2 * (3 - 2 * intVal);
                    % Set the state of the animated function for this frame
                    operatingFunction(n, :) = (1 - intFunc) * app.currentFx(xSteps) + intFunc * app.newFx(xSteps);
                end
                % Delete full quality plot
                delete(app.fxPlot)
            else % See above
                for n = 1:1:app.animationFrames
                    intVal = n / app.animationFrames;
                    intFunc = intVal^2 * (3 - 2 * intVal);
                    operatingFunction(n, :) = (1 - intFunc) * app.currentGx(xSteps) + intFunc * app.newGx(xSteps);
                end
                delete(app.gxPlot)
            end

            % NaN out imaginary values
            operatingFunction(imag(operatingFunction) ~= 0) = NaN;
            
            %% Run the animation

            heldFn = plot(app.UIAxes, 0, 0);
            for n = 1:1:app.animationFrames
                % Plot this frame using precalculated arrays
                delete(heldFn)
                heldFn = plot(app.UIAxes, xSteps, operatingFunction(n, :));
                heldFn.Color = "#AAAAAA";
                % Reset the limits so screen doesn't move
                app.UIAxes.XLim = xLimits;
                app.UIAxes.YLim = yLimits;
                % Frame delay
                pause(0.005)
            end

            delete(heldFn)

            % Redraw sum approximations and graphs in full quality
            redrawFns(app, currentFunction);
            redrawSums(app);
        end
        
        function inputLimiter(app, funcIn)
            pause(0.5)
            if funcIn == 'f' && now > app.fxLastIn + 0.000005
               redrawAnim(app, funcIn);
            elseif funcIn == 'g' && now > app.gxLastIn + 0.000005
             redrawAnim(app, funcIn);
            end
        end
        
        function redrawSums(app)
           %% DISCLAIMER: This function primarily consists of heavily refactored ChatGPT code
           % Redraws the plot with the current inputs.
           % Also updates area and error calculations.

           width = (app.upperBound - app.lowerBound) / app.sumSteps; % Width of each rectangle
           rectSpace = linspace(app.lowerBound, app.upperBound, app.sumSteps + 1); % Ensure correct spacing

           fIsFn = isequal(app.fxDrawing, [0 0]);
           gIsFn = isequal(app.gxDrawing, [0 0]);
           
           switch app.sumType
               case 'L'
                    x_rects = rectSpace(1:end-1);
               case 'R'
                    x_rects = rectSpace(1:end-1);
               case 'M'
                    x_rects = (rectSpace(1:end-1) + rectSpace(2:end)) / 2;
               case 'T'
                    x_rects = rectSpace(1:end-1);
               case 'S'
                    x_rects = rectSpace;
               otherwise
                   error('Bad sumType in redrawSums')
           end

           % Replace x_rects == 0 with epsilon to avoid div by zero error
           x_rects(x_rects == 0) = eps;

           % Sample heights
           switch app.sumType
               case 'L'
                    if fIsFn
                        heights_f = app.newFx(rectSpace(1:end-1));
                    else
                        heights_f = interpretDrawnFunctionValues(app, app.fxDrawing(), rectSpace(1:end-1));
                    end
            
                    if gIsFn
                        heights_g = app.newGx(rectSpace(1:end-1));
                    else
                        heights_g = interpretDrawnFunctionValues(app, app.gxDrawing(), rectSpace(1:end-1));
                    end
               case 'R'
                   if fIsFn
                        heights_f = app.newFx(rectSpace(2:end));
                   else
                        heights_f = interpretDrawnFunctionValues(app, app.fxDrawing(), rectSpace(2:end));
                   end
               
                   if gIsFn
                        heights_g = app.newGx(rectSpace(2:end));
                   else
                        heights_g = interpretDrawnFunctionValues(app, app.gxDrawing(), rectSpace(2:end));
                   end               
               case 'M'
                   midpoints = (rectSpace(1:end-1) + rectSpace(2:end)) / 2;

                   if fIsFn
                        heights_f = app.newFx(midpoints);
                   else
                        heights_f = interpretDrawnFunctionValues(app, app.fxDrawing(), midpoints);
                   end
                 
                   if gIsFn
                        heights_g = app.newGx(midpoints);
                   else
                        heights_g = interpretDrawnFunctionValues(app, app.gxDrawing(), midpoints);
                   end
               otherwise
                   % Get rect height values from either drawn or typed fn
                   if fIsFn
                        heights_f = app.newFx(x_rects);
                   else
                        heights_f = interpretDrawnFunctionValues(app, app.fxDrawing(), x_rects);
                        fInt = interpretDrawnFunctionValues(app, app.fxDrawing(), x_rects + width);
                   end
               
                   if gIsFn
                        heights_g = app.newGx(x_rects);
                   else
                        heights_g = interpretDrawnFunctionValues(app, app.gxDrawing(), x_rects);
                        gInt = interpretDrawnFunctionValues(app, app.gxDrawing(), x_rects + width);
                   end
           end

           % Remove imaginary values
           heights_f(imag(heights_f) ~= 0) = NaN;
           heights_g(imag(heights_g) ~= 0) = NaN;
           
           % Calculate Sum Areas
            switch app.sumType
                case {'L', 'R'}
                    diffs = heights_f - heights_g;
                    validMask = isfinite(diffs);
                    area_sum = sum(diffs(validMask) * width);
            
                case 'M'
                    diffs = heights_f - heights_g;
                    validMask = isfinite(diffs);
                    area_sum = sum(diffs(validMask) * width);
            
                case 'T'
                    if fIsFn
                        fx_vals = app.newFx(rectSpace);
                    else
                        fx_vals = interpretDrawnFunctionValues(app, app.fxDrawing(), rectSpace);
                    end
                    
                    if gIsFn
                        gx_vals = app.newGx(rectSpace);
                    else
                        gx_vals = interpretDrawnFunctionValues(app, app.gxDrawing(), rectSpace);
                    end
                    
                    % Trapezoidal Rule: sum 0.5 * width * (f(x_i) + f(x_{i+1}))
                    trapArea_f = 0.5 * width * sum(fx_vals(1:end-1) + fx_vals(2:end));
                    trapArea_g = 0.5 * width * sum(gx_vals(1:end-1) + gx_vals(2:end));
                    area_sum = trapArea_f - trapArea_g;
                case 'S'
                    % Evaluate fx and gx depending on whether they are typed or drawn
                    if fIsFn
                        fx_vals = double(app.newFx(rectSpace));
                    else
                        fx_vals = interpretDrawnFunctionValues(app, app.fxDrawing(), rectSpace);
                    end
                    
                    if gIsFn
                        gx_vals = double(app.newGx(rectSpace));
                    else
                        gx_vals = interpretDrawnFunctionValues(app, app.gxDrawing(), rectSpace);
                    end
                    
                    % Define NaN-safe summation
                    safeSum = @(x) sum(x(~isnan(x)));
                    
                    % Use Simpson’s Rule: (b - a)/3n * [f(a) + 4f(x1) + 2f(x2) + ... + f(b)]
                    % Apply to both functions, then subtract
                    area_f = (width / 3) * ( ...
                        safeSum(fx_vals(1)) + ...
                        4 * safeSum(fx_vals(2:2:end-1)) + ...
                        2 * safeSum(fx_vals(3:2:end-2)) + ...
                        safeSum(fx_vals(end)) ...
                    );
                    
                    area_g = (width / 3) * ( ...
                        safeSum(gx_vals(1)) + ...
                        4 * safeSum(gx_vals(2:2:end-1)) + ...
                        2 * safeSum(gx_vals(3:2:end-2)) + ...
                        safeSum(gx_vals(end)) ...
                    );
                    
                    area_sum = area_f - area_g;
                otherwise
                    error('Bad sumType in redrawSums');
            end
           
            % Update text displayed for user
            updateSumReadouts(app, area_sum, fIsFn, gIsFn);

            %% Rendering
           % Clear old sum
            if ~isempty(app.sumPlot)
                validHandles = isgraphics(app.sumPlot);
                delete(app.sumPlot(validHandles));
            end

           % Draw sum with appropriate shape depending on method
           switch app.sumType
               case 'T'
                   for n = 1:length(x_rects)
                       % Coordinates for the trapezoid (slanted sides)
                       x_trap = [x_rects(n), x_rects(n) + width];  % x-coordinates of the trapezoid

                       if fIsFn
                            y_trap_f = [heights_f(n), app.newFx(x_rects(n) + width)]; % y-coordinates for function f
                       else
                            y_trap_f = [heights_f(n), fInt(n)]; % y-coords for drawing f
                       end
                   
                       if gIsFn
                            y_trap_g = [heights_g(n), app.newGx(x_rects(n) + width)]; % y-coordinates for function g
                       else
                            y_trap_g = [heights_g(n), gInt(n)]; % y-coords for drawing g
                       end     
            
                       % Remove imaginary values
                       y_trap_f(imag(y_trap_f) ~= 0) = NaN;
                       y_trap_g(imag(y_trap_g) ~= 0) = NaN;

                       % Plot trapezoids between the two functions
                       app.sumPlot(n) = fill(app.UIAxes, [x_trap, fliplr(x_trap)], [y_trap_f, fliplr(y_trap_g)], 'g', 'EdgeColor', 'k', 'FaceAlpha', 0.5);
                   end
               case 'S'
                    % Calculate the midpoints for Simpson's rule
                    midpoints = (x_rects(1:end-1) + x_rects(2:end)) / 2;
                    
                    % Plot parabolic segments between each pair of points
                    for n = 1:length(x_rects)-1
                        % Get the three points for the current parabola
                        x_vals = [x_rects(n), midpoints(n), x_rects(n+1)];
                        
                        % Ensure y_vals_f and y_vals_g are of type double
                        
                        if fIsFn
                            y_vals_f = double(app.newFx(x_vals));
                        else
                            y_vals_f = interpretDrawnFunctionValues(app, app.fxDrawing(), x_vals);
                        end

                        if gIsFn
                            y_vals_g = double(app.newGx(x_vals));
                        else
                            y_vals_g = interpretDrawnFunctionValues(app, app.gxDrawing(), x_vals);
                        end
                        
                        % Remove imaginary values
                        y_vals_f(imag(y_vals_f) ~= 0) = NaN;
                        y_vals_g(imag(y_vals_g) ~= 0) = NaN;
                        
                        % Create the parabolic curve for the difference between functions f and g
                        x_parabola = linspace(x_vals(1), x_vals(3), 100);
                        
                        % Only interpolate using finite values
                        valid_f = isfinite(y_vals_f);
                        valid_g = isfinite(y_vals_g);
                        
                        % Ensure we have at least 2 points to interpolate
                        if sum(valid_f) >= 2
                            y_parabola_f = interp1(x_vals(valid_f), y_vals_f(valid_f), x_parabola, 'pchip', NaN);
                        else
                            y_parabola_f = NaN(size(x_parabola));
                        end
                        
                        if sum(valid_g) >= 2
                            y_parabola_g = interp1(x_vals(valid_g), y_vals_g(valid_g), x_parabola, 'pchip', NaN);
                        else
                            y_parabola_g = NaN(size(x_parabola));
                        end
                        
                        % Only fill if there are enough valid points to draw a shape
                        if all(~isnan(y_parabola_f)) && all(~isnan(y_parabola_g))
                            app.sumPlot(n) = fill(app.UIAxes, ...
                                [x_parabola, fliplr(x_parabola)], ...
                                [y_parabola_f, fliplr(y_parabola_g)], ...
                                'g', 'EdgeColor', 'k', 'FaceAlpha', 0.5);
                        end
                    end
               case 'M'
                   for n = 1:length(x_rects)
                       left = x_rects(n) - width/2;
                       x_rect = [left, left + width, left + width, left];
                       y_rect = [heights_g(n), heights_g(n), heights_f(n), heights_f(n)];
                       app.sumPlot(n) = fill(app.UIAxes, x_rect, y_rect, 'g', 'EdgeColor', 'k', 'FaceAlpha', 0.5);
                   end
               otherwise
                   for n = 1:length(x_rects)
                       x_rect = [x_rects(n), x_rects(n)+width, x_rects(n)+width, x_rects(n)];
                       y_rect = [heights_g(n), heights_g(n), heights_f(n), heights_f(n)];
                       app.sumPlot(n) = fill(app.UIAxes, x_rect, y_rect, 'g', 'EdgeColor', 'k', 'FaceAlpha', 0.5);
                   end
           end
        end
        %% DRAWING FUNCTIONS

        function startLine(app, mousePos)
            % Keep bounds static
            xLimits = app.UIAxes.XLim;
            yLimits = app.UIAxes.YLim;

            % Plot mousePos
            app.drawnLinePoint = plot(app.UIAxes, mousePos(1), mousePos(2), 'o');
            
            if app.activeDrawing == 'F'
                if isequal(app.fxDrawing, [0 0])
                    app.fxDrawing(end, :) = mousePos;
                end
                if length(app.fxDrawing(:, 1)) == 2
                    checkDir(app, app.fxDrawing)
                end
            else
                if isequal(app.gxDrawing, [0 0])
                    app.gxDrawing(end, :) = mousePos;
                end
                if length(app.gxDrawing(:, 1)) == 2
                    checkDir(app, app.gxDrawing)
                end
            end


            % Keep bounds static
            app.UIAxes.XLim = xLimits;
            app.UIAxes.YLim = yLimits;
        end
        
        %% Complete a started line
        function endLine(app, mousePos)
            if app.activeDrawing == 'F'
                if (isscalar(app.fxDrawing(:, 1))) || (app.increasing && (mousePos(1) > max(app.fxDrawing(:, 1)))) || (~app.increasing && (mousePos(1) < min(app.fxDrawing(:, 1))))
                    app.fxDrawing(end + 1, :) = mousePos;
                else
                    warndlg('invalid pos')
                    return
                end
                delete(app.fxPlot);
                app.fxPlot = plot(app.UIAxes, app.fxDrawing(:, 1), app.fxDrawing(:, 2));
            else
                if (isscalar(app.gxDrawing(:, 1))) || (app.increasing && (mousePos(1) > max(app.gxDrawing(:, 1)))) || (~app.increasing && (mousePos(1) < min(app.gxDrawing(:, 1))))
                    app.gxDrawing(end + 1, :) = mousePos;
                else
                    warndlg('invalid pos')
                    return
                end
                delete(app.gxPlot);
                app.gxPlot = plot(app.UIAxes, app.gxDrawing(:, 1), app.gxDrawing(:, 2));
            end

            redrawSums(app);
            delete(app.drawnLinePoint);
            startLine(app, mousePos);
        end
        
        %% Check if a set of two points is "increasing" or "decreasing" by comparing their x values.
        function checkDir(app, lineSet)
            if lineSet(2, 1) > lineSet(1,1)
                app.increasing = true;
            else 
                app.increasing = false;
            end
        end

        %% Toggle on/off a specified UI element depending on inputs
        function toggleUIElement(app, buttonName, enabled)
            eval("app." + buttonName + ".Visible = " + enabled + ";");
            eval("app." + buttonName + ".Enable = " + enabled + ";");
        end
        
        %% Redraw functions at max quality
        function redrawFns(app, funcIn)
            if funcIn == 'f'
                delete(app.fxPlot)
                app.fxPlot = fplot(app.UIAxes, app.newFx);
                app.currentFx = app.newFx;
                app.fxPlot.Color = "#0072BD";
            else
                delete(app.gxPlot)
                app.gxPlot = fplot(app.UIAxes, app.newGx);
                app.currentGx = app.newGx;
                app.gxPlot.Color = "#D95319";
            end
        end
        
        %% Pass in a number of xpositions and a matrix of point positions. 
        % Reads the position as if a line were drawn between the points listed.
        % Reads NaN if outside of bounds
        function results = interpretDrawnFunctionValues(~, funcMatrix, xPosArray)
            results = zeros(1, length(xPosArray));
            if length(funcMatrix(:,1)) > 1
                for n = 1:length(xPosArray)
                    value = interp1(funcMatrix(:,1), funcMatrix(:,2), xPosArray(n));
                    %if ~isnan(value)
                    results(n) = value;
                    %end
                end
            end
        end
        
        %% Pass in app, the calculated sum, and if F or G are written or drawn
        function updateSumReadouts(app, area_sum, fIsFn, gIsFn)
        alertNumeric = false;
        a = double(app.lowerBound);
        b = double(app.upperBound);

        maxTime = 1.0; % seconds to allow symbolic integration before fallback
    
        % Protect against 0 substeps
        if isnan(area_sum)
            area_sum = 0;
        end

        % --- f(x) ---
        if fIsFn
            useSymbolic = true;
            tic;
            try
                temp_int_f = int(app.newFx, app.lowerBound, app.upperBound);
                elapsed = toc;
    
                % If integration took too long or result is still symbolic
                if elapsed > maxTime || ~isscalar(double(temp_int_f))
                    useSymbolic = false;
                else
                    temp_int_f = double(temp_int_f);
                end
            catch
                useSymbolic = false;
            end
    
            if ~useSymbolic
                alertNumeric = true;
                fx_handle = matlabFunction(app.newFx, 'Vars', sym('x'));
                temp_int_f = integral(fx_handle, a, b, 'ArrayValued', true);
            end
    
            area_int = temp_int_f;
        else
            area_int = trapz(app.fxDrawing(:, 1), app.fxDrawing(:, 2));
        end
    
        % --- g(x) ---
        if gIsFn
            useSymbolic = true;
            tic;
            try
                temp_int_g = int(app.newGx, app.lowerBound, app.upperBound);
                elapsed = toc;
    
                if elapsed > maxTime || ~isscalar(double(temp_int_g))
                    useSymbolic = false;
                else
                    temp_int_g = double(temp_int_g);
                end
            catch
                useSymbolic = false;
            end
    
            if ~useSymbolic
                alertNumeric = true;
                gx_handle = matlabFunction(app.newGx, 'Vars', sym('x'));
                temp_int_g = integral(gx_handle, a, b, 'ArrayValued', true);
            end
    
            area_int = area_int - temp_int_g;
        else
            area_int = area_int - trapz(app.gxDrawing(:, 1), app.gxDrawing(:, 2));
        end

        % Write values to user-end readout
        % If symbolic integration wasn't possible, alert the user
        if alertNumeric
            app.IntegralLabel.FontColor = "#FFFF55";
            app.IntegralLabel.Tooltip = "Integral couldn't be calculated - using numeric integration instead.";
            app.IntegralLabel.Text = sprintf("! Approximate Integral: %g", area_int);
        else
            app.IntegralLabel.Text = sprintf("Integral: %g", area_int);
            app.IntegralLabel.FontColor = 'k';
            app.IntegralLabel.Tooltip = '';
        end

        app.SumLabel.Text = sprintf("Sum: %g", area_sum);
        app.SumErrorLabel.Text = sprintf("Sum Error: %g", area_int - area_sum);
        end
        
        %% Interprets input strings using regex to account for implicit multiplication and other values (eg. eulers number)
        % DISCLAIMER: The following function was written using ChatGPT.
            function output = addImplicitMultiplication(~, input)
                output = input;
                
                % Special case: Handle 'xexp' to 'x*exp' before other replacements
                output = regexprep(output, '(x)(exp\()', '$1*exp('); % Specifically for xexp(x)
                
                % Step 0: Replace 'ln' with 'log'
                output = regexprep(output, '\<ln\>', 'log');
                
                % Step 0.1: Replace exact "ex" and "xe" with placeholders
                output = regexprep(output, '(?<![a-zA-Z0-9_])ex(?![a-zA-Z0-9_])', '_EX_TIMES_X_');
                output = regexprep(output, '(?<![a-zA-Z0-9_])xe(?![a-zA-Z0-9_])', '_X_TIMES_EXP_');
                
                % Step 0.2: Protect known function names
                functions = {'exp', 'sin', 'cos', 'tan', 'log', 'sqrt'};
                placeholders = {'_FUNC_EXP_', '_FUNC_SIN_', '_FUNC_COS_', '_FUNC_TAN_', '_FUNC_LOG_', '_FUNC_SQRT_'};
                for i = 1:length(functions)
                    output = regexprep(output, ['(?<![a-zA-Z_])' functions{i} '(?![a-zA-Z_])'], placeholders{i});
                end
                
                % Step 1: Add * between digit and x or (
                output = regexprep(output, '(\d)(x|\()', '$1*$2');
                
                % Step 2: Add * between x and digit or (
                output = regexprep(output, '(x)(\d|\()', '$1*$2');
                
                % Step 3: Add * between ) and digit or x
                output = regexprep(output, '(\))(\d|x)', '$1*$2');
                
                % Step 4: Add * between x and letters (excluding 'e' to avoid touching exp stuff)
                output = regexprep(output, '(x)([a-df-zA-DF-Z])', '$1*$2');
                
                % Step 5: Handle implicit multiplication between constants and functions
                % For example, 2sin(x) -> 2*sin(x), exp(3)(2) -> exp(3)*2
                output = regexprep(output, '(\d)([a-zA-Z_]+\()', '$1*$2'); % between digits and functions
                output = regexprep(output, '([a-zA-Z_]+\d)(\()', '$1*$2'); % between function and digits
                
                % Step 6: Handle implicit multiplication between numbers in parentheses and functions
                output = regexprep(output, '\((\d+)\)([a-zA-Z_]+\()', '($1)*$2'); % e.g., (3)sin(x) -> (3)*sin(x)
                
                % Step 7: Add * between adjacent parentheses (e.g., (3)(4) -> (3)*(4))
                output = regexprep(output, '\)(?=\()', ')*'); % Between two adjacent parentheses, add '*'
                
                % Step 8: Handle implicit multiplication with 'e' (e.g., (4)e -> (4)*e, e(3) -> e*3, 6e -> 6*e, e9 -> e*9)
                output = regexprep(output, '(\d)(e)', '$1*e'); % Between digit and 'e', insert '*'
                output = regexprep(output, '(e)(\()', 'e*('); % Between 'e' and '(', insert '*'
                output = regexprep(output, '(\))e', '$1*e'); % Between ')' and 'e', insert '*'
                output = regexprep(output, 'e(\d)', 'exp(1)*$1'); % Between 'e' and digit, insert '*'
                
                % Step 9: Replace standalone 'e' with exp(1)
                output = regexprep(output, '\<e\>', 'exp(1)');
                
                % Step 10: Restore function names
                for i = 1:length(functions)
                    output = strrep(output, placeholders{i}, functions{i});
                end
                
                % Step 11: Restore ex/xe placeholders
                output = strrep(output, '_EX_TIMES_X_', 'exp(1)*x');
                output = strrep(output, '_X_TIMES_EXP_', 'x*exp(1)');
            end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set bounds and plot axes
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            xline(app.UIAxes, 0, "Color", "#AAAAAA")
            yline(app.UIAxes, 0, "Color", "#AAAAAA")
            % Enable hold so functions can be drawn without being deleted
            hold(app.UIAxes, "on");
            % Initialize plots in the scope of UIAxes
            app.fxPlot = plot(app.UIAxes, 0, 0);
            app.gxPlot = plot(app.UIAxes, 0, 0);
        end

        % Value changing function: fxEditField
        function fxEditFieldValueChanging(app, event)
            rawValue = event.Value;
            
            if isempty(rawValue)
                rawValue = "0";
            end
        
            % Preprocess to insert implicit multiplication
            processedValue = app.addImplicitMultiplication(rawValue);
        
            try
                app.newFx = symfun(str2sym(processedValue), app.x);
                if numel(symvar(app.newFx)) > 1
                    error("Function must be in terms of x only.")
                end
                shouldRedraw(app);
            catch
                try
                    app.fxPlot.Color = "#AAAAAA";
                catch
                end
            end
        end

        % Value changing function: gxEditField
        function gxEditFieldValueChanging(app, event)
            rawValue = event.Value;
            
            if isempty(rawValue)
                rawValue = "0";
            end
        
            % Preprocess to insert implicit multiplication
            processedValue = app.addImplicitMultiplication(rawValue);
        
            try
                app.newGx = symfun(str2sym(processedValue), app.x);
                if numel(symvar(app.newGx)) > 1
                    error("Function must be in terms of x only.")
                end
                shouldRedraw(app);
            catch
                try
                    app.fxPlot.Color = "#AAAAAA";
                catch
                end
            end
        end

        % Selection changed function: SelectSumTypeButtonGroup
        function SelectSumTypeButtonGroupSelectionChanged(app, event)
            % When changed, update the sum type used in calculations and rendering
            app.sumType = app.SelectSumTypeButtonGroup.SelectedObject.Text;

            % If Simpsons is enabled, force even
            if app.sumType == 'S' && mod(app.sumSteps,2) && app.sumSteps == app.upperBoundBox.Value
                app.sumSteps = app.sumSteps-1;
                app.StepsSlider.Value = app.sumSteps;
                app.StepsBox.Value = app.sumSteps;
            elseif app.sumType == 'S' && mod(app.sumSteps,2)
                app.sumSteps = app.sumSteps+1;
                app.StepsSlider.Value = app.sumSteps;
                app.StepsBox.Value = app.sumSteps;
            end

            redrawSums(app)
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            % When changed, update the lower bound for sum calculations
            app.lowerBound = app.aEditField.Value;
            % Update min value of b edit value
            app.bEditField.Limits = [app.aEditField.Value + 1, Inf];

            redrawSums(app)
        end

        % Value changed function: StepsSlider
        function StepsSliderValueChanged(app, event)
            % When changed, update the number of steps for sum calculations
            steps = app.StepsSlider.Value;
            steps = round(steps);

            % If Simpsons is enabled, force even
            if app.sumType == 'S' && mod(steps,2) && steps == app.upperBoundBox.Value
                steps = steps-1;
            elseif app.sumType == 'S' && mod(steps,2)
                steps = steps+1;
            end

            app.StepsSlider.Value = steps;
            app.StepsBox.Value = steps;

            app.sumSteps = steps;
            redrawSums(app)        
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            % When changed, update the upper bound for sum calculations
            app.upperBound = app.bEditField.Value;
            % Update max value of a edit value
            app.aEditField.Limits = [-Inf, app.bEditField.Value - 1];

            redrawSums(app)
        end

        % Menu selected function: animHigherQualityMenu
        function animHigherQualityMenuSelected(app, event)
            app.animationFrames = 20;
            app.animationPoints = 50;
            app.animationEnabled = true;
        end

        % Menu selected function: animHigherPerformanceMenu
        function animHigherPerformanceMenuSelected(app, event)
            app.animationFrames = 5;
            app.animationPoints = 30;
            app.animationEnabled = true;
        end

        % Menu selected function: animDisabledMenu
        function animDisabledMenuSelected(app, event)
            app.animationEnabled = false;
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            AboutBox();
        end

        % Value changed function: StepsBox
        function StepsBoxValueChanged(app, event)
            % When changed, update the number of steps for sum calculations
            steps = app.StepsBox.Value;
            steps = min(max(steps, app.StepsSlider.Limits(1)), app.StepsSlider.Limits(2));

            % If Simpsons is enabled, force even
            if app.sumType == 'S' && mod(steps,2) && steps == app.upperBoundBox.Value
                steps = steps-1;
            elseif app.sumType == 'S' && mod(steps,2)
                steps = steps+1;
            end

            app.StepsSlider.Value = steps;
            app.StepsBox.Value = steps;

            app.sumSteps = steps;
            redrawSums(app)
        end

        % Value changed function: lowerBoundBox
        function lowerBoundBoxValueChanged(app, event)
            % Update slider limits
            app.StepsSlider.Limits(1) = app.lowerBoundBox.Value;
            % Update min value of upper box
            app.upperBoundBox.Limits = [app.lowerBoundBox.Value + 1, Inf];
        end

        % Value changed function: upperBoundBox
        function upperBoundBoxValueChanged(app, event)
            % Update slider limits
            app.StepsSlider.Limits(2) = app.upperBoundBox.Value;
            % Update max value of lower box
            app.lowerBoundBox.Limits = [0, app.upperBoundBox.Value - 1];
        end

        % Button pushed function: fDrawButton
        function fDrawButtonPushed(app, event)
            % Clear fx
            app.newFx = symfun(0, sym("x"));
            app.fxEditField.Value = "";
            shouldRedraw(app);

            % Disable UI Elements
            toggleUIElement(app, "fDrawButton", false);
            toggleUIElement(app, "fxEditField", false);

            % Enable Drawing UI Elements
            toggleUIElement(app, "fStartDrawingButton", true);
            toggleUIElement(app, "fClearDrawingButton", true);
            toggleUIElement(app, "fTypeButton", true);
        end

        % Button pushed function: gDrawButton
        function gDrawButtonPushed(app, event)
            % Clear gx
            app.newGx = symfun(0, sym("x"));
            app.gxEditField.Value = "";
            shouldRedraw(app);

            % Disable Typing UI Elements
            toggleUIElement(app, "gDrawButton", false);
            toggleUIElement(app, "gxEditField", false);

            % Enable Drawing UI Elements
            toggleUIElement(app, "gStartDrawingButton", true);
            toggleUIElement(app, "gClearDrawingButton", true);
            toggleUIElement(app, "gTypeButton", true);
        end

        % Button pushed function: fTypeButton
        function fTypeButtonPushed(app, event)
            % Disable Drawing UI Elements
            toggleUIElement(app, "fStartDrawingButton", false)
            toggleUIElement(app, "fClearDrawingButton", false)
            toggleUIElement(app, "fTypeButton", false)
            app.fStartDrawingButton.Text = "Draw New f(x)";
            if app.activeDrawing ~= 'G'
                app.activeDrawing = '';
            end

            % Enable Typing UI Elements
            toggleUIElement(app, "fDrawButton", true)
            toggleUIElement(app, "fxEditField", true)

            delete(app.fxPlot)
            app.fxPlot = plot(app.UIAxes, 0, 0);
            delete(app.drawnLinePoint);
            app.fxDrawing = [0 0];
            redrawSums(app);
        end

        % Button pushed function: gTypeButton
        function gTypeButtonPushed(app, event)
            % Disable Drawing UI Elements
            toggleUIElement(app, "gStartDrawingButton", false)
            toggleUIElement(app, "gClearDrawingButton", false)
            toggleUIElement(app, "gTypeButton", false)
            app.gStartDrawingButton.Text = "Draw New g(x)";
            if app.activeDrawing ~= 'F'
                app.activeDrawing = '';
            end

            % Enable Typing UI Elements
            toggleUIElement(app, "gDrawButton", true)
            toggleUIElement(app, "gxEditField", true)

            delete(app.gxPlot)
            app.gxPlot = plot(app.UIAxes, 0, 0);
            delete(app.drawnLinePoint);
            app.gxDrawing = [0 0];
            redrawSums(app);
        end

        % Button pushed function: fStartDrawingButton
        function fStartDrawingButtonPushed(app, event)
            if isempty(app.activeDrawing) || app.activeDrawing ~= 'F'
                    app.activeDrawing = 'F';
                    app.fxDrawing = [0 0];
                    delete(app.fxPlot);
                    delete(app.drawnLinePoint);
                    redrawSums(app);

                    % Toggle button texts in the case g(x) was selected
                    app.gStartDrawingButton.Text = "Draw New g(x)";
                    app.fStartDrawingButton.Text = "Finish Drawing";
                    app.mousePosLabel.Text = "x:    y: ";
            else
                    app.activeDrawing = '';
                    app.fStartDrawingButton.Text = "Draw New f(x)";
                    app.mousePosLabel.Text = '';
                    delete(app.drawnLinePoint)
            end
        end

        % Button pushed function: gStartDrawingButton
        function gStartDrawingButtonPushed(app, event)
            if isempty(app.activeDrawing) || app.activeDrawing ~= 'G'
                    app.activeDrawing = 'G';
                    app.gxDrawing = [0 0];
                    delete(app.gxPlot);
                    delete(app.drawnLinePoint);
                    redrawSums(app);

                    app.fStartDrawingButton.Text = "Draw New f(x)";
                    app.gStartDrawingButton.Text = "Finish Drawing";
                    app.mousePosLabel.Text = "x:    y: ";
            else
                    app.activeDrawing = '';
                    app.gStartDrawingButton.Text = "Draw New g(x)";
                    app.mousePosLabel.Text = '';
                    delete(app.drawnLinePoint)
            end
        end

        % Window button motion function: NumIntGraphingCalculatorUIFigure
        function NumIntGraphingCalculatorUIFigureWindowButtonMotion(app, event)
            if isempty(app.activeDrawing)
                return
            end

            mousePos = event.IntersectionPoint([1 2]);

            if ~(app.ctrlDown || app.shiftDown)
                mousePos = round(mousePos);
            end
            
            if ~isnan(mousePos)
                app.mousePosLabel.Text = "x: " + mousePos(1) + "   y: " + mousePos(2);
            else
                app.mousePosLabel.Text = "x:    y: ";
            end
        end

        % Window key press function: NumIntGraphingCalculatorUIFigure
        function NumIntGraphingCalculatorUIFigureWindowKeyPress(app, event)
            if isequal(event.Key, 'control')
                app.ctrlDown = true;
            elseif isequal(event.Key, 'shift')
                app.shiftDown = true;
            end
        end

        % Window key release function: NumIntGraphingCalculatorUIFigure
        function NumIntGraphingCalculatorUIFigureWindowKeyRelease(app, event)
            if isequal(event.Key, 'control')
                app.ctrlDown = false;
            elseif isequal(event.Key, 'shift')
                app.shiftDown = false;
            end
        end

        % Callback function
        function xLowerBoundBoxValueChanged(app, event)
            app.UIAxes.XLim(1) = app.xLowerBoundBox.Value;
            app.UIAxes.XLim(2) = app.xUpperBoundBox.Value;
            app.UIAxes.YLim(1) = app.yLowerBoundBox.Value;
            app.UIAxes.YLim(2) = app.yUpperBoundBox.Value;
        end

        % Window scroll wheel function: NumIntGraphingCalculatorUIFigure
        function NumIntGraphingCalculatorUIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            zoomQuantity = -verticalScrollAmount * verticalScrollCount;
            
            xLim = app.UIAxes.XLim;
            yLim = app.UIAxes.YLim;

            small = 1 / zoomQuantity;
            large = 2 - small;

            app.UIAxes.XLim = [(large * xLim(1) + small * xLim(2)) / 2, (small * xLim(1) + large * xLim(2)) / 2];
            app.UIAxes.YLim = [(large * yLim(1) + small * yLim(2)) / 2, (small * yLim(1) + large * yLim(2)) / 2];
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            if ~isempty(app.activeDrawing)
                mousePos = event.IntersectionPoint([1 2]);
    
                if ~(app.shiftDown || app.ctrlDown)
                    mousePos = round(mousePos);
                end
    

                if app.activeDrawing == 'F'
                    if isequal(app.fxDrawing, [0 0])
                        startLine(app, mousePos);
                    else
                        endLine(app, mousePos);
                    end
                else
                    if isequal(app.gxDrawing, [0 0])
                        startLine(app, mousePos);
                    else
                        endLine(app, mousePos);
                    end
                end
            end
        end

        % Button pushed function: fClearDrawingButton
        function fClearDrawingButtonPushed(app, event)
            if app.activeDrawing == 'F'
                app.activeDrawing = '';
                app.fStartDrawingButton.Text = "Draw New f(x)";
                delete(app.drawnLinePoint)
            end
            
            app.fxDrawing = [0 0];
            delete(app.fxPlot)
            redrawSums(app);
        end

        % Button pushed function: gClearDrawingButton
        function gClearDrawingButtonPushed(app, event)
            if app.activeDrawing == 'G'
                app.activeDrawing = '';
                app.gStartDrawingButton.Text = "Draw New g(x)";
                delete(app.drawnLinePoint)
            end

            app.gxDrawing = [0 0];
            delete(app.gxPlot)
            redrawSums(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.NumIntGraphingCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {455, 455};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {219, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NumIntGraphingCalculatorUIFigure = uifigure('Visible', 'off');
            app.NumIntGraphingCalculatorUIFigure.Name = 'NumInt Graphing Calculator';
            app.NumIntGraphingCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.NumIntGraphingCalculatorUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @NumIntGraphingCalculatorUIFigureWindowButtonMotion, true);
            app.NumIntGraphingCalculatorUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @NumIntGraphingCalculatorUIFigureWindowScrollWheel, true);
            app.NumIntGraphingCalculatorUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @NumIntGraphingCalculatorUIFigureWindowKeyPress, true);
            app.NumIntGraphingCalculatorUIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @NumIntGraphingCalculatorUIFigureWindowKeyRelease, true);
            app.SettingsMenu = uimenu(app.NumIntGraphingCalculatorUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.expSettings = uimenu(app.SettingsMenu);
            app.expSettings.Text = 'Experimental Settings';
            app.changeBoundsOfSteps = uimenu(app.expSettings);
            app.changeBoundsOfSteps.Text = 'Enable Custom Step Bounds';
            app.animationMenu = uimenu(app.expSettings);
            app.animationMenu.Text = 'Animation';
            app.animHigherQualityMenu = uimenu(app.animationMenu);
            app.animHigherQualityMenu.MenuSelectedFcn = createCallbackFcn(app, @animHigherQualityMenuSelected, true);
            app.animHigherQualityMenu.Text = 'Higher Quality';
            app.animHigherPerformanceMenu = uimenu(app.animationMenu);
            app.animHigherPerformanceMenu.MenuSelectedFcn = createCallbackFcn(app, @animHigherPerformanceMenuSelected, true);
            app.animHigherPerformanceMenu.Text = 'Higher Performance';
            app.animDisabledMenu = uimenu(app.animationMenu);
            app.animDisabledMenu.MenuSelectedFcn = createCallbackFcn(app, @animDisabledMenuSelected, true);
            app.animDisabledMenu.Text = 'Disabled';
            app.AboutMenu = uimenu(app.SettingsMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.NumIntGraphingCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.fxEditFieldLabel = uilabel(app.LeftPanel);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fStartDrawingButton = uibutton(app.LeftPanel, 'push');
            app.fStartDrawingButton.ButtonPushedFcn = createCallbackFcn(app, @fStartDrawingButtonPushed, true);
            app.fStartDrawingButton.Enable = 'off';
            app.fStartDrawingButton.Visible = 'off';
            app.fStartDrawingButton.Tooltip = {'Draw New f(x)'};
            app.fStartDrawingButton.Text = 'Draw New f(x)';
            app.fxEditField = uieditfield(app.LeftPanel, 'text');
            app.fxEditField.ValueChangingFcn = createCallbackFcn(app, @fxEditFieldValueChanging, true);
            app.fxEditField.Placeholder = 'Enter f(x)';
            app.fClearDrawingButton = uibutton(app.LeftPanel, 'push');
            app.fClearDrawingButton.ButtonPushedFcn = createCallbackFcn(app, @fClearDrawingButtonPushed, true);
            app.fClearDrawingButton.Enable = 'off';
            app.fClearDrawingButton.Visible = 'off';
            app.fClearDrawingButton.Tooltip = {'Clear f(x)'};
            app.fClearDrawingButton.Text = 'C';
            app.fTypeButton = uibutton(app.LeftPanel, 'push');
            app.fTypeButton.ButtonPushedFcn = createCallbackFcn(app, @fTypeButtonPushed, true);
            app.fTypeButton.Enable = 'off';
            app.fTypeButton.Visible = 'off';
            app.fTypeButton.Tooltip = {'Enter Function by Text'};
            app.fTypeButton.Text = 'T';
            app.fDrawButton = uibutton(app.LeftPanel, 'push');
            app.fDrawButton.ButtonPushedFcn = createCallbackFcn(app, @fDrawButtonPushed, true);
            app.fDrawButton.Tooltip = {'Draw Function by Hand'};
            app.fDrawButton.Text = 'D';
            app.gxEditFieldLabel = uilabel(app.LeftPanel);
            app.gxEditFieldLabel.Text = 'g(x)';
            app.gStartDrawingButton = uibutton(app.LeftPanel, 'push');
            app.gStartDrawingButton.ButtonPushedFcn = createCallbackFcn(app, @gStartDrawingButtonPushed, true);
            app.gStartDrawingButton.Enable = 'off';
            app.gStartDrawingButton.Visible = 'off';
            app.gStartDrawingButton.Tooltip = {'Draw New g(x)'};
            app.gStartDrawingButton.Text = 'Draw New g(x)';
            app.gxEditField = uieditfield(app.LeftPanel, 'text');
            app.gxEditField.ValueChangingFcn = createCallbackFcn(app, @gxEditFieldValueChanging, true);
            app.gxEditField.Placeholder = 'Enter g(x)';
            app.gClearDrawingButton = uibutton(app.LeftPanel, 'push');
            app.gClearDrawingButton.ButtonPushedFcn = createCallbackFcn(app, @gClearDrawingButtonPushed, true);
            app.gClearDrawingButton.Enable = 'off';
            app.gClearDrawingButton.Visible = 'off';
            app.gClearDrawingButton.Tooltip = {'Clear g(x)'};
            app.gClearDrawingButton.Text = 'C';
            app.gTypeButton = uibutton(app.LeftPanel, 'push');
            app.gTypeButton.ButtonPushedFcn = createCallbackFcn(app, @gTypeButtonPushed, true);
            app.gTypeButton.Enable = 'off';
            app.gTypeButton.Visible = 'off';
            app.gTypeButton.Tooltip = {'Enter Function by Text'};
            app.gTypeButton.Text = 'T';
            app.gDrawButton = uibutton(app.LeftPanel, 'push');
            app.gDrawButton.ButtonPushedFcn = createCallbackFcn(app, @gDrawButtonPushed, true);
            app.gDrawButton.Tooltip = {'Draw Function by Hand'};
            app.gDrawButton.Text = 'D';
            app.aEditField = uieditfield(app.LeftPanel, 'numeric');
            app.aEditField.Limits = [-Inf 9];
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.Value = -10;
            app.aEditFieldLabel = uilabel(app.LeftPanel);
            app.aEditFieldLabel.Text = 'a';
            app.bEditFieldLabel = uilabel(app.LeftPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.LeftPanel, 'numeric');
            app.bEditField.Limits = [-9 Inf];
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.bEditField.Value = 10;
            app.SelectSumTypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.SelectSumTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectSumTypeButtonGroupSelectionChanged, true);
            app.SelectSumTypeButtonGroup.Title = 'Select Sum Type';
            app.LeftButton = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.LeftButton.Tooltip = {'Left Sum'};
            app.LeftButton.Text = 'L';
            app.LeftButton.Value = true;
            app.RightButton = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.RightButton.Tooltip = {'Right Sum'};
            app.RightButton.Text = 'R';
            app.MidButton = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.MidButton.Tooltip = {'Midpoint Sum'};
            app.MidButton.Text = 'M';
            app.TrapButton = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.TrapButton.Tooltip = {'Trapezoidal Sum'};
            app.TrapButton.Text = 'T';
            app.SimpsonButton = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.SimpsonButton.Tooltip = {'Simpson''s Rule Sum'};
            app.SimpsonButton.Text = 'S';
            app.StepsSliderLabel = uilabel(app.LeftPanel);
            app.StepsSliderLabel.Text = '# Steps';
            app.StepsSlider = uislider(app.LeftPanel);
            app.StepsSlider.Limits = [0 40];
            app.StepsSlider.ValueChangedFcn = createCallbackFcn(app, @StepsSliderValueChanged, true);
            app.StepsSlider.Value = 20;
            app.lowerBoundBox = uieditfield(app.LeftPanel, 'numeric');
            app.lowerBoundBox.Limits = [0 19];
            app.lowerBoundBox.RoundFractionalValues = 'on';
            app.lowerBoundBox.ValueChangedFcn = createCallbackFcn(app, @lowerBoundBoxValueChanged, true);
            app.upperBoundBox = uieditfield(app.LeftPanel, 'numeric');
            app.upperBoundBox.Limits = [1 Inf];
            app.upperBoundBox.RoundFractionalValues = 'on';
            app.upperBoundBox.ValueChangedFcn = createCallbackFcn(app, @upperBoundBoxValueChanged, true);
            app.upperBoundBox.Value = 40;
            app.StepsBox = uieditfield(app.LeftPanel, 'numeric');
            app.StepsBox.Limits = [0 Inf];
            app.StepsBox.RoundFractionalValues = 'on';
            app.StepsBox.ValueChangedFcn = createCallbackFcn(app, @StepsBoxValueChanged, true);
            app.StepsBox.Value = 20;
            app.IntegralLabel = uilabel(app.LeftPanel);
            app.IntegralLabel.Text = 'Integral:';
            app.SumLabel = uilabel(app.LeftPanel);
            app.SumLabel.Text = 'Sum: ';
            app.SumErrorLabel = uilabel(app.LeftPanel);
            app.SumErrorLabel.Text = 'Sum Error:';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Sum Visualization')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.mousePosLabel = uilabel(app.RightPanel);
            app.mousePosLabel.Tooltip = {''};
            app.mousePosLabel.Text = '';
            % Show the figure after all components are created
            app.NumIntGraphingCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewMint

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NumIntGraphingCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NumIntGraphingCalculatorUIFigure)
        end
    end
end